classdef infectiousDiseaseOutbreak < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        InfoMenu                        matlab.ui.container.Menu
        InitialConditionsMenu           matlab.ui.container.Menu
        ODECoefficientsMenu             matlab.ui.container.Menu
        GraphingExportingMenu           matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        TimeSpandaysLabel               matlab.ui.control.Label
        TimeSpan                        matlab.ui.control.NumericEditField
        InitialConditionsPanel          matlab.ui.container.Panel
        InitialPopulationofSusceptibleIndividualsLabel  matlab.ui.control.Label
        healthy_i                       matlab.ui.control.NumericEditField
        InitialPopulationofDiseasedIndividualsLabel  matlab.ui.control.Label
        infected_i                      matlab.ui.control.NumericEditField
        InitialPopulationofImmuneIndividualsEditFieldLabel  matlab.ui.control.Label
        immune_i                        matlab.ui.control.NumericEditField
        odeCoefficients                 matlab.ui.container.Panel
        RateofInfectionEditFieldLabel   matlab.ui.control.Label
        rateInfection                   matlab.ui.control.NumericEditField
        AverageDurationofInfectionEditFieldLabel  matlab.ui.control.Label
        durationInfection               matlab.ui.control.NumericEditField
        immLossBool                     matlab.ui.control.CheckBox
        RateofImmunityLossEditFieldLabel  matlab.ui.control.Label
        rateImmLoss                     matlab.ui.control.NumericEditField
        NatrualDeathRateEditFieldLabel  matlab.ui.control.Label
        natDeathRate                    matlab.ui.control.NumericEditField
        GraphButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        MaximumInfectedPanel            matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        InitialConditionsInfoPanel      matlab.ui.container.Panel
        CloseInfo_IC                    matlab.ui.control.Button
        label_infoIC                    matlab.ui.control.Label
        ODECoefficientsInfoPanel        matlab.ui.container.Panel
        CloseInfo_ODE                   matlab.ui.control.Button
        label_infoODE                   matlab.ui.control.Label
        GraphingandExportingInfoPanel   matlab.ui.container.Panel
        CloseInfo_GE                    matlab.ui.control.Button
        label_infoGE                    matlab.ui.control.Label
        LegendLocationDropDownLabel     matlab.ui.control.Label
        LegendLocationDropDown          matlab.ui.control.DropDown
        HelpMenuPanel                   matlab.ui.container.Panel
        CloseInfo_HM                    matlab.ui.control.Button
        label_infoHM                    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        exportConfirm                   matlab.ui.control.Label
        InfectiousDiseaseOutbreakModelLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ob1 % array data
        legLoc % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ExportMenu.Visible = 0;
            
            app.legLoc = 'northeast';
            
            app.InitialConditionsInfoPanel.Visible = 0;
            app.ODECoefficientsInfoPanel.Visible = 0;
            app.GraphingandExportingInfoPanel.Visible = 0;
            app.HelpMenuPanel.Visible = 0;
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            tspan = 0:app.TimeSpan.Value;
            y0 = [app.healthy_i.Value app.infected_i.Value app.immune_i.Value];
            
            [t,app.ob1] = ode45(@outbreakModel,tspan,y0);
            
            S = app.ob1(:,1);
            D = app.ob1(:,2);
            I = app.ob1(:,3);
            
            app.ExportMenu.Visible = 1;
            app.exportConfirm.Visible = 0;
            
            maxInf = max(D);
            dayMax = find(D==maxInf,1);
            
            % bonus strings
            string1 = strcat(num2str(floor(maxInf)),{' '},'Infected on day',{' '},num2str(dayMax));
            
            if app.rateImmLoss.Value == 0
                titleString = 'full immunity';
            else
                titleString = 'partial immunity';
            end
            string2 = strcat('Model for Outbreaks,',{' '},titleString);
            
            app.Label.Text = string1;
            
            app.UIAxes.cla      %clears previous graphs
            
            plot(app.UIAxes,S,'r');
            hold(app.UIAxes,'on');
            plot(app.UIAxes,D,'color',[0 .5 0]);
            hold(app.UIAxes,'on');
            plot(app.UIAxes,I,'b');
            hold(app.UIAxes,'on');
            scatter(app.UIAxes,dayMax,maxInf,'g');
            title(app.UIAxes,string2);
            xlabel(app.UIAxes,'Days');
            ylabel(app.UIAxes,'Number of Individuals');
            legend(app.UIAxes,  'y1, Susceptible Individuals',...
                                'y2, Diseased Individuals', ...
                                'y3, Immune Individuals', ...
                                'Maximum infected', ...
                                'Location',app.legLoc ...
                                );
            
            function ob = outbreakModel(t,y)
                beta = app.natDeathRate.Value;
                k = app.rateInfection.Value;
                tau = app.durationInfection.Value;
                rho = app.rateImmLoss.Value;
            
                ob = [  -k*y(1)*y(2)-beta*y(1)+rho*y(3); ...
                        k*y(1)*y(2)-y(2)/tau; ...
                        y(2)/tau-beta*y(3)-rho*y(3)];
            end
        end

        % Value changed function: immLossBool
        function immLossBoolValueChanged(app, event)
            value = app.immLossBool.Value;
            if value == 1
                app.rateImmLoss.Visible = 1;
                app.RateofImmunityLossEditFieldLabel.Visible = 1;
            elseif value == 0
                app.rateImmLoss.Visible = 0;
                app.RateofImmunityLossEditFieldLabel.Visible = 0;
                app.rateImmLoss.Value = 0;
            end
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            app.HelpMenuPanel.Visible = 1;
            
            app.InitialConditionsInfoPanel.Visible = 0;
            app.ODECoefficientsInfoPanel.Visible = 0;
            app.GraphingandExportingInfoPanel.Visible = 0;
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            xlswrite('array_data',app.ob1)
            app.exportConfirm.Text = 'Export Successful!';
            app.exportConfirm.Visible = 1;
        end

        % Menu selected function: InitialConditionsMenu
        function InitialConditionsMenuSelected(app, event)
            app.InitialConditionsInfoPanel.Visible = 1;
            
            app.GraphingandExportingInfoPanel.Visible = 0;
            app.ODECoefficientsInfoPanel.Visible = 0;
            app.HelpMenuPanel.Visible = 0;
        end

        % Menu selected function: ODECoefficientsMenu
        function ODECoefficientsMenuSelected(app, event)
            app.ODECoefficientsInfoPanel.Visible = 1;
            
            app.InitialConditionsInfoPanel.Visible = 0;
            app.GraphingandExportingInfoPanel.Visible = 0;
            app.HelpMenuPanel.Visible = 0;
        end

        % Menu selected function: GraphingExportingMenu
        function GraphingExportingMenuSelected(app, event)
            app.GraphingandExportingInfoPanel.Visible = 1;
            
            app.InitialConditionsInfoPanel.Visible = 0;
            app.ODECoefficientsInfoPanel.Visible = 0;
            app.HelpMenuPanel.Visible = 0;
        end

        % Button pushed function: CloseInfo_IC
        function CloseInfo_ICPushed(app, event)
            app.InitialConditionsInfoPanel.Visible = 0;
        end

        % Button pushed function: CloseInfo_ODE
        function CloseInfo_ODEButtonPushed(app, event)
            app.ODECoefficientsInfoPanel.Visible = 0;
        end

        % Button pushed function: CloseInfo_GE
        function CloseInfo_GEButtonPushed(app, event)
            app.GraphingandExportingInfoPanel.Visible = 0;
        end

        % Value changed function: LegendLocationDropDown
        function LegendLocationDropDownValueChanged(app, event)
            value = app.LegendLocationDropDown.Value;
            if strcmp('Top Right',value)
                app.legLoc = 'northeast';
            elseif strcmp('Center Right',value)
                app.legLoc = 'east';
            elseif strcmp('Bottom Right',value)
                app.legLoc = 'southeast';
            end
        end

        % Button pushed function: CloseInfo_HM
        function CloseInfo_HMButtonPushed(app, event)
            app.HelpMenuPanel.Visible = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.HelpMenu = uimenu(app.Menu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.InfoMenu = uimenu(app.Menu);
            app.InfoMenu.Text = 'Info';
            app.InitialConditionsMenu = uimenu(app.InfoMenu);
            app.InitialConditionsMenu.MenuSelectedFcn = createCallbackFcn(app, @InitialConditionsMenuSelected, true);
            app.InitialConditionsMenu.Text = 'Initial Conditions';
            app.ODECoefficientsMenu = uimenu(app.InfoMenu);
            app.ODECoefficientsMenu.MenuSelectedFcn = createCallbackFcn(app, @ODECoefficientsMenuSelected, true);
            app.ODECoefficientsMenu.Text = 'ODE Coefficients';
            app.GraphingExportingMenu = uimenu(app.InfoMenu);
            app.GraphingExportingMenu.MenuSelectedFcn = createCallbackFcn(app, @GraphingExportingMenuSelected, true);
            app.GraphingExportingMenu.Text = 'Graphing + Exporting';
            app.ExportMenu = uimenu(app.Menu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Text = 'Export';
            app.TimeSpandaysLabel = uilabel(app.UIFigure);
            app.TimeSpandaysLabel.Text = {'Time Span'; '(days)'; ''};
            app.TimeSpan = uieditfield(app.UIFigure, 'numeric');
            app.TimeSpan.Limits = [0 Inf];
            app.TimeSpan.RoundFractionalValues = 'on';
            app.TimeSpan.Value = 180;
            app.InitialConditionsPanel = uipanel(app.UIFigure);
            app.InitialConditionsPanel.Title = 'Initial Conditions';
            app.InitialPopulationofSusceptibleIndividualsLabel = uilabel(app.InitialConditionsPanel);
            app.InitialPopulationofSusceptibleIndividualsLabel.Text = {'Initial Population of'; 'Susceptible Individuals'};
            app.healthy_i = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.healthy_i.Limits = [0 Inf];
            app.healthy_i.RoundFractionalValues = 'on';
            app.healthy_i.Value = 100000;
            app.InitialPopulationofDiseasedIndividualsLabel = uilabel(app.InitialConditionsPanel);
            app.InitialPopulationofDiseasedIndividualsLabel.Text = {'Initial Population of'; 'Diseased Individuals'};
            app.infected_i = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.infected_i.Limits = [0 Inf];
            app.infected_i.RoundFractionalValues = 'on';
            app.infected_i.Value = 100;
            app.InitialPopulationofImmuneIndividualsEditFieldLabel = uilabel(app.InitialConditionsPanel);
            app.InitialPopulationofImmuneIndividualsEditFieldLabel.Text = {'Initial Population of'; 'Immune Individuals'};
            app.immune_i = uieditfield(app.InitialConditionsPanel, 'numeric');
            app.immune_i.Limits = [0 Inf];
            app.immune_i.RoundFractionalValues = 'on';
            app.odeCoefficients = uipanel(app.UIFigure);
            app.odeCoefficients.Title = 'ODE Coefficients';
            app.RateofInfectionEditFieldLabel = uilabel(app.odeCoefficients);
            app.RateofInfectionEditFieldLabel.Text = 'Rate of Infection';
            app.rateInfection = uieditfield(app.odeCoefficients, 'numeric');
            app.rateInfection.Limits = [0 Inf];
            app.rateInfection.Value = 4e-06;
            app.AverageDurationofInfectionEditFieldLabel = uilabel(app.odeCoefficients);
            app.AverageDurationofInfectionEditFieldLabel.Text = {'Average Duration of'; 'Infection'};
            app.durationInfection = uieditfield(app.odeCoefficients, 'numeric');
            app.durationInfection.Limits = [0 Inf];
            app.durationInfection.Value = 7;
            app.immLossBool = uicheckbox(app.odeCoefficients);
            app.immLossBool.ValueChangedFcn = createCallbackFcn(app, @immLossBoolValueChanged, true);
            app.immLossBool.Text = 'Immunity Loss';
            app.immLossBool.Value = true;
            app.RateofImmunityLossEditFieldLabel = uilabel(app.odeCoefficients);
            app.RateofImmunityLossEditFieldLabel.Text = 'Rate of Immunity Loss';
            app.rateImmLoss = uieditfield(app.odeCoefficients, 'numeric');
            app.rateImmLoss.Limits = [0 Inf];
            app.rateImmLoss.Value = 0.03;
            app.NatrualDeathRateEditFieldLabel = uilabel(app.odeCoefficients);
            app.NatrualDeathRateEditFieldLabel.Text = 'Natrual Death Rate';
            app.natDeathRate = uieditfield(app.odeCoefficients, 'numeric');
            app.natDeathRate.Value = 1e-05;
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph!';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.MaximumInfectedPanel = uipanel(app.UIFigure);
            app.MaximumInfectedPanel.Title = 'Maximum Infected';
            app.Label = uilabel(app.MaximumInfectedPanel);
            app.Label.Text = {''; ''};
            app.InitialConditionsInfoPanel = uipanel(app.UIFigure);
            app.InitialConditionsInfoPanel.Title = 'Initial Conditions Info';
            app.CloseInfo_IC = uibutton(app.InitialConditionsInfoPanel, 'push');
            app.CloseInfo_IC.ButtonPushedFcn = createCallbackFcn(app, @CloseInfo_ICPushed, true);
            app.CloseInfo_IC.Text = 'Close';
            app.label_infoIC = uilabel(app.InitialConditionsInfoPanel);
            app.label_infoIC.Text = {'Initial Conditions are the original values '; 'when time equals zero.'; ''; 'These typically dictate the transient '; 'shape of the graph before the values'; 'begin stablizing.'; ''; 'You can change the starting number of'; 'healthy, diseased, and immune individuals'};
            app.ODECoefficientsInfoPanel = uipanel(app.UIFigure);
            app.ODECoefficientsInfoPanel.Title = 'ODE Coefficients Info';
            app.CloseInfo_ODE = uibutton(app.ODECoefficientsInfoPanel, 'push');
            app.CloseInfo_ODE.ButtonPushedFcn = createCallbackFcn(app, @CloseInfo_ODEButtonPushed, true);
            app.CloseInfo_ODE.Text = 'Close';
            app.label_infoODE = uilabel(app.ODECoefficientsInfoPanel);
            app.label_infoODE.Text = {'These are the coefficients for the'; 'Ordinary Differential Equation that will'; 'be graphed. For more information on the'; 'ODE, check the Help menu.'; ''; 'It is suggested that rate of infection'; 'be on the order of 10^-6, the natural'; 'death rate be on the order of 10^-5, and'; 'the rate of immunity loss be on the order of'; '10^-2.'};
            app.GraphingandExportingInfoPanel = uipanel(app.UIFigure);
            app.GraphingandExportingInfoPanel.Title = 'Graphing and Exporting Info';
            app.CloseInfo_GE = uibutton(app.GraphingandExportingInfoPanel, 'push');
            app.CloseInfo_GE.ButtonPushedFcn = createCallbackFcn(app, @CloseInfo_GEButtonPushed, true);
            app.CloseInfo_GE.Text = 'Close';
            app.label_infoGE = uilabel(app.GraphingandExportingInfoPanel);
            app.label_infoGE.Text = {'Given that the all entries have valid values,'; 'you can press the Graph! button to graph the'; 'resultant ODE.'; ''; 'Exporting will be available under the Menu'; 'only after a graph has been made. This'; 'exports a matrix of three columns that'; 'contain the values of susceptible, diseased, '; 'and immune individuals over time. It is '; 'exported as a .xls to the current folder '; 'MATLAB has open.'};
            app.LegendLocationDropDownLabel = uilabel(app.UIFigure);
            app.LegendLocationDropDownLabel.Text = 'Legend Location';
            app.LegendLocationDropDown = uidropdown(app.UIFigure);
            app.LegendLocationDropDown.Items = {'Top Right', 'Center Right', 'Bottom Right'};
            app.LegendLocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LegendLocationDropDownValueChanged, true);
            app.LegendLocationDropDown.Value = 'Top Right';
            app.HelpMenuPanel = uipanel(app.UIFigure);
            app.HelpMenuPanel.Title = 'Help Menu';
            app.HelpMenuPanel.Visible = 'off';
            app.CloseInfo_HM = uibutton(app.HelpMenuPanel, 'push');
            app.CloseInfo_HM.ButtonPushedFcn = createCallbackFcn(app, @CloseInfo_HMButtonPushed, true);
            app.CloseInfo_HM.Text = 'Close';
            app.label_infoHM = uilabel(app.HelpMenuPanel);
            app.label_infoHM.Text = {'Welcome to the Infectious Diesease Outbreak'; 'MATLAB application! This application allows you'; 'to model the spread of an infectious disease in a population.'; ''; 'This simulation calculates the results of a system'; 'of ordinary differential equations given certain'; 'Initial Conditions and graphs the values over time.'; ''; 'The applicaiton also tells you when the maximum '; 'number of infected people is reached, and how'; 'many were infected on that day (rounded down).'; ''; '-'; 'The system of differential equations has its form outlined on the right. '; ''; '''S'' represents the total number of Susceptible Individuals. '; '''D'' represents the total number of Diseased Individuals. '; '''I'' represents the total number of Immune Individuals.'; ''; 'Coefficient ''k'' represents the rate of infection in days^-1. '; 'Coefficient ''beta'' represents the natural death rate in days^-1. '; 'Coefficient ''tau'' represents the average duration of infection in days. '; 'Coefficient ''rho'' represents the rate of immunity loss in days^-1.'};
            app.Image = uiimage(app.HelpMenuPanel);
            app.Image.ImageSource = 'ode_partial.png';
            app.Image2 = uiimage(app.HelpMenuPanel);
            app.Image2.ImageSource = 'disease clipart.png';
            app.exportConfirm = uilabel(app.UIFigure);
            app.exportConfirm.Visible = 'off';
            app.InfectiousDiseaseOutbreakModelLabel = uilabel(app.UIFigure);
            app.InfectiousDiseaseOutbreakModelLabel.Text = 'Infectious Disease Outbreak Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = infectiousDiseaseOutbreak

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end